classdef Synthesiser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        ImportMenu            matlab.ui.container.Menu
        ExportMenu            matlab.ui.container.Menu
        ClearMenu             matlab.ui.container.Menu
        PlaybackMenu          matlab.ui.container.Menu
        PlayMenu              matlab.ui.container.Menu
        PauseMenu             matlab.ui.container.Menu
        StopMenu              matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        TabGroup              matlab.ui.container.TabGroup
        WaveformTab           matlab.ui.container.Tab
        WaveformContainer     matlab.ui.container.GridLayout
        WaveformAxes          matlab.ui.control.UIAxes
        SpectrogramTab        matlab.ui.container.Tab
        SpectrogramContainer  matlab.ui.container.GridLayout
        SpectrogramAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sample % The sample loaded from file for the synthesiser.
        sampleRate % The sample rate of the currently loaded audio sample.
        sampleName % The name of the file being used as a sample.

        audioPlayer % The current audio player object in use
    end
    
    methods (Access = private)
        % --== Audio file interaction methods ==-- %
        % Method to import an audio sample
        function [y, Fs] = importAudio(app)
            [file, path] = uigetfile(["*.mp3;*.flac;*.m4a;*.mp4;*.oga;*.ogg;*.wav", "Audio Files"]);

            if path ~= 0
                app.clearAudio();
                fullPath = append(path, file);
                [y, Fs] = audioread(fullPath);
                
                app.sample = y(:, 1); % Convert sample to mono to simplfy future operations
                app.sampleRate = Fs;
                app.sampleName = file;
            end
        end
        
        % Method to export the audio resulting from the synthesiser changes
        function fullPath = exportAudio(app)
            [file, path] = uiputfile(["*.flac;*.oga;*.ogg;*.wav", "Audio files"]);

            if path ~= 0
                fullPath = append(path, file);
                audiowrite(fullPath, app.sample, app.sampleRate);
            end
        end
        
        % Method to clear the current audio sample from storage
        function clearAudio(app)
            app.sample = [];
            app.sampleRate = 0;
            app.sampleName = "None";

            app.audioPlayer = {};
        end
        
        
        % --== Graphing methods ==-- %
        % Method to plot the graphs in the app
        function plotGraphs(app)
            if ~isempty(app.sample)
                % Define variables used by both plots
                y = app.sample;
                Fs = app.sampleRate;
                dur = length(y)/Fs; % Calculate duration
                xLimit = [0, length(y)];

                % Plot Spectrogram
                axes = app.SpectrogramAxes;

                [S, F, T] = spectrogram(y, 100);
                imagesc(axes, [0, length(y)], [0, 1], log(abs(S)));
                set(axes, "YDir", "normal")
                xlim(axes, xLimit);
                ylim(axes, [0, 1]);
                disableDefaultInteractivity(axes);

                % Plot Waveform
                axes = app.WaveformAxes;
                plot(axes, y, "b");
                xlim(axes, xLimit);
                ylim(axes, [-1, 1]);
                disableDefaultInteractivity(axes);
            end
        end
        
        % Method to clear graphs of data
        function clearGraphs(app)
            cla(app.WaveformAxes);
            cla(app.SpectrogramAxes);
        end
    

        % --== Audio playback methods ==-- %
        % Play the current audio generated by app.processAudio
        % If app.audioPlayer is defined, will resume playback
        function playAudio(app)
            % Create a new audio player if one is not already assigned.
            if isempty(app.audioPlayer)
                y = app.processAudio();
                Fs = app.sampleRate;
                if ~isempty(y)
                    app.audioPlayer = audioplayer(y, Fs);
                    app.audioPlayer.StopFcn = @(~, ~) app.stopAudio();
                end
            end

            if ~isempty(app.audioPlayer)
                resume(app.audioPlayer);
            end
        end
    
        % Pause the currently playing audio
        function pauseAudio(app)
            if ~isempty(app.audioPlayer)
                pause(app.audioPlayer);
            end
        end
        
        % Stop the currently playing audio
        function stopAudio(app)
            if ~isempty(app.audioPlayer)
                % Check if audio player is still playing so we dont
                % automatically stop after pausing.
                if app.audioPlayer.isplaying()
                    stop(app.audioPlayer);
                    app.audioPlayer = {};
                end
            end
        end
           

        % --== Audio processing methods ==-- %
        % Process the audio according to the applications state
        function results = processAudio(app)
            % TODO: Functionality for processing audio
            results = app.sample;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
            app.importAudio();
            app.plotGraphs();
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            app.exportAudio();
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            app.clearAudio();
            app.clearGraphs();
        end

        % Menu selected function: PlayMenu
        function PlayMenuSelected(app, event)
            app.playAudio();
        end

        % Menu selected function: PauseMenu
        function PauseMenuSelected(app, event)
            app.pauseAudio();
        end

        % Menu selected function: StopMenu
        function StopMenuSelected(app, event)
            app.stopAudio();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Separator = 'on';
            app.ImportMenu.Accelerator = 'o';
            app.ImportMenu.Text = 'Import';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Accelerator = 's';
            app.ExportMenu.Text = 'Export';
            app.ClearMenu = uimenu(app.FileMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Separator = 'on';
            app.ClearMenu.Accelerator = 'c';
            app.ClearMenu.Text = 'Clear';
            app.PlaybackMenu = uimenu(app.UIFigure);
            app.PlaybackMenu.Text = 'Playback';
            app.PlayMenu = uimenu(app.PlaybackMenu);
            app.PlayMenu.MenuSelectedFcn = createCallbackFcn(app, @PlayMenuSelected, true);
            app.PlayMenu.Accelerator = 'e';
            app.PlayMenu.Text = 'Play';
            app.PauseMenu = uimenu(app.PlaybackMenu);
            app.PauseMenu.MenuSelectedFcn = createCallbackFcn(app, @PauseMenuSelected, true);
            app.PauseMenu.Accelerator = 'q';
            app.PauseMenu.Text = 'Pause';
            app.StopMenu = uimenu(app.PlaybackMenu);
            app.StopMenu.MenuSelectedFcn = createCallbackFcn(app, @StopMenuSelected, true);
            app.StopMenu.Accelerator = 'r';
            app.StopMenu.Text = 'Stop';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.WaveformTab = uitab(app.TabGroup);
            app.WaveformTab.Title = 'Waveform';
            app.WaveformContainer = uigridlayout(app.WaveformTab);
            app.WaveformAxes = uiaxes(app.WaveformContainer);
            title(app.WaveformAxes, 'Waveform')
            xlabel(app.WaveformAxes, 'Sample')
            ylabel(app.WaveformAxes, 'Magnitude')
            app.SpectrogramTab = uitab(app.TabGroup);
            app.SpectrogramTab.Title = 'Spectrogram';
            app.SpectrogramContainer = uigridlayout(app.SpectrogramTab);
            app.SpectrogramAxes = uiaxes(app.SpectrogramContainer);
            title(app.SpectrogramAxes, 'Spectrogram')
            xlabel(app.SpectrogramAxes, 'Sample')
            ylabel(app.SpectrogramAxes, 'Normalised Frequency (log)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Synthesiser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end